classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LayoutMain               matlab.ui.container.GridLayout
        LayoutStimulus           matlab.ui.container.GridLayout
        StimulusSelectPanel      matlab.ui.container.Panel
        RunButton                matlab.ui.control.Button
        StimulusSelect           matlab.ui.control.DropDown
        StimulusLabel            matlab.ui.control.Label
        StimulusPanel            matlab.ui.container.Panel
        LayoutProperties         matlab.ui.container.GridLayout
        SyncPanel                matlab.ui.container.Panel
        ScreenSyncConnected      matlab.ui.control.Lamp
        ScreenSync               matlab.ui.control.DropDown
        SyncLabel                matlab.ui.control.Label
        SubjectPanel             matlab.ui.container.Panel
        SubjectInfo              matlab.ui.control.Label
        SubjectFOVInfo           matlab.ui.control.Label
        SubjectPositionZEdit     matlab.ui.control.NumericEditField
        ZmmLabel                 matlab.ui.control.Label
        SubjectPositionYEdit     matlab.ui.control.NumericEditField
        YmmLabel                 matlab.ui.control.Label
        SubjectPositionXEdit     matlab.ui.control.NumericEditField
        XmmEditFieldLabel        matlab.ui.control.Label
        SubjectCenteredCheckBox  matlab.ui.control.CheckBox
        SubjectPositionLabel     matlab.ui.control.Label
        SubjectFocalLength       matlab.ui.control.NumericEditField
        FocallengthmmLabel       matlab.ui.control.Label
        SubjectAdd               matlab.ui.control.Button
        SubjectSelect            matlab.ui.control.DropDown
        MonitorPanel             matlab.ui.container.Panel
        ConnectedLamp            matlab.ui.control.Lamp
        ConnectButton            matlab.ui.control.Button
        ScreenSelect             matlab.ui.control.DropDown
        ScreenDropDownLabel      matlab.ui.control.Label
        MonitorCalibrateButton   matlab.ui.control.Button
        MonitorYpxEditField      matlab.ui.control.NumericEditField
        MonitorXpxEditField      matlab.ui.control.NumericEditField
        XYpxEditFieldLabel       matlab.ui.control.Label
        MonitorYmmEditField      matlab.ui.control.NumericEditField
        MonitorXmmEditField      matlab.ui.control.NumericEditField
        XYmmEditFieldLabel       matlab.ui.control.Label
        MonitorAdd               matlab.ui.control.Button
        MonitorSelect            matlab.ui.control.DropDown
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (GetAccess = public, SetAccess = protected)
        Config              % Configuration file (application config).

        Stimulus            % Stimulus app handle.

        SyncModules         % List of available sync modules.
        SyncHandle          % Sync module handle.
    end
    
    properties(Access = private,Hidden)
        UIEnable = {}       % UI elements with 'Enable' property.
        EnableStatus = {}   % Saved 'Enable' status.
    end

    methods (Access = private)

        function syncStatus(app,good)
            if good
                app.ScreenSyncConnected.Color = [0.0,1.0,0.0];
            else
                app.ScreenSyncConnected.Color = [1.0,0.0,0.0];
            end
        end
       
        function syncInit(app)
            good = false;
            try 
                app.SyncHandle.init(app.Config);
                good = app.SyncHandle.good();

                if good
                    app.Config.set('sync.name', app.ScreenSync.Value);
                end
            catch me
                disp(me)
            end
               
            app.syncStatus(good);
        end
        
        function updateMonitorData(app, monitor)
            % FIXME
            % 'FOV: 111.8° × 79.4°\n         5072 µm × 3603 µm (estimated)'
        end

        function updateSubjectInfo(app)
            % FIXME
        end

        function enableUI(app,value)
            if value
                if isempty(app.UIEnable)
                    return
                end

                for i = 1:length(app.UIEnable)
                    app.UIEnable{i}.Enable = app.EnableStatus{i};
                end
            else
                if isempty(app.UIEnable)
                    allChildren = findobj(app.UIFigure);
                    
                    for i = 1:length(allChildren)
                        if isprop(allChildren(i), 'Enable')
                            app.UIEnable{end+1} = allChildren(i);
                        end
                    end

                    app.EnableStatus = cell(1, length(allChildren));
                end

                for i = 1:length(app.UIEnable)
                    app.EnableStatus{i} = app.UIEnable{i}.Enable;
                    app.UIEnable{i}.Enable = false;
                end
            end
        end

    end
    
    methods (Access = public)
       
        function syncLoad(app,name)
            idx = find(strcmp({app.SyncModules.name},name));
            app.SyncHandle = rfstim.sync.(app.SyncModules(idx).module)(app);
            app.syncInit();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load config
            app.Config = appconfig(Program = 'bborfstim', File = 'global.mat');

            % setup stimuli
            % FIXME

            % setup sync
            syncdir = mfilename('fullpath');
            [syncdir,~,~] = fileparts(syncdir);
            syncdir = fullfile(syncdir, '+sync');

            modlist = rfstim.util.modules(syncdir);

            % setup sync modules
            app.SyncModules = modlist;
            app.ScreenSync.Items = {app.SyncModules.name};

            app.ScreenSync.Value = app.Config.get('sync.name', app.ScreenSync.Items{1});
            app.syncLoad(app.ScreenSync.Value);
        end

        % Value changed function: ScreenSync
        function ScreenSyncValueChanged(app, event)
            value = app.ScreenSync.Value;

            if strcmp(value, app.SyncHandle.name())
                app.SyncHandle.init(app.Config);
            else
                app.enableUI(false);
                app.ScreenSyncConnected.Color = [1.0,0.0,0.0];
                try
                    app.syncLoad(value)
                catch
                end
                app.enableUI(true);

                if ~app.SyncHandle.good()
                    app.ScreenSync.Value = app.ScreenSync.Items{1};
                end
            end
        end

        % Value changed function: MonitorSelect
        function MonitorSelectValueChanged(app, event)
            app.updateMonitorData(app.MonitorSelect.Value);
        end

        % Button pushed function: MonitorAdd
        function MonitorAddButtonPushed(app, event)
            % FIXME
        end

        % Value changed function: ScreenSelect
        function ScreenSelectValueChanged(app, event)
            % value = app.ScreenSelect.Value;
            % FIXME
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % FIXME
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LayoutMain = uigridlayout(app.UIFigure);
            app.LayoutProperties = uigridlayout(app.LayoutMain);
            app.MonitorPanel = uipanel(app.LayoutProperties);
            app.MonitorPanel.Title = 'Monitor';
            app.UIAxes = uiaxes(app.MonitorPanel);
            xlabel(app.UIAxes, 'V (1)')
            ylabel(app.UIAxes, 'I (cd/m²)')
            zlabel(app.UIAxes, 'Z')
            app.MonitorSelect = uidropdown(app.MonitorPanel);
            app.MonitorSelect.Items = {'Monitor 1', 'Monitor 2', 'Monitor 3', 'Monitor 4'};
            app.MonitorSelect.ValueChangedFcn = createCallbackFcn(app, @MonitorSelectValueChanged, true);
            app.MonitorSelect.Value = 'Monitor 1';
            app.MonitorAdd = uibutton(app.MonitorPanel, 'push');
            app.MonitorAdd.ButtonPushedFcn = createCallbackFcn(app, @MonitorAddButtonPushed, true);
            app.MonitorAdd.Text = 'Add...';
            app.XYmmEditFieldLabel = uilabel(app.MonitorPanel);
            app.XYmmEditFieldLabel.Text = 'X,Y (mm):';
            app.MonitorXmmEditField = uieditfield(app.MonitorPanel, 'numeric');
            app.MonitorXmmEditField.Editable = 'off';
            app.MonitorXmmEditField.Value = 590.4;
            app.MonitorYmmEditField = uieditfield(app.MonitorPanel, 'numeric');
            app.MonitorYmmEditField.Editable = 'off';
            app.MonitorYmmEditField.Value = 332.1;
            app.XYpxEditFieldLabel = uilabel(app.MonitorPanel);
            app.XYpxEditFieldLabel.Text = 'X,Y (px):';
            app.MonitorXpxEditField = uieditfield(app.MonitorPanel, 'numeric');
            app.MonitorXpxEditField.Editable = 'off';
            app.MonitorXpxEditField.Value = 2560;
            app.MonitorYpxEditField = uieditfield(app.MonitorPanel, 'numeric');
            app.MonitorYpxEditField.Editable = 'off';
            app.MonitorYpxEditField.Value = 1440;
            app.MonitorCalibrateButton = uibutton(app.MonitorPanel, 'push');
            app.MonitorCalibrateButton.Enable = 'off';
            app.MonitorCalibrateButton.Text = 'Calibration';
            app.ScreenDropDownLabel = uilabel(app.MonitorPanel);
            app.ScreenDropDownLabel.Text = 'Screen:';
            app.ScreenSelect = uidropdown(app.MonitorPanel);
            app.ScreenSelect.Items = {'''0''', '''1''', '''2'''};
            app.ScreenSelect.ValueChangedFcn = createCallbackFcn(app, @ScreenSelectValueChanged, true);
            app.ScreenSelect.Value = '''1''';
            app.ConnectButton = uibutton(app.MonitorPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectedLamp = uilamp(app.MonitorPanel);
            app.SubjectPanel = uipanel(app.LayoutProperties);
            app.SubjectPanel.Title = 'Subject';
            app.SubjectSelect = uidropdown(app.SubjectPanel);
            app.SubjectSelect.Items = {'Generic'};
            app.SubjectSelect.Value = 'Generic';
            app.SubjectAdd = uibutton(app.SubjectPanel, 'push');
            app.SubjectAdd.Enable = 'off';
            app.SubjectAdd.Text = 'Add...';
            app.FocallengthmmLabel = uilabel(app.SubjectPanel);
            app.FocallengthmmLabel.Text = 'Focal length (mm):';
            app.SubjectFocalLength = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectFocalLength.Limits = [0 Inf];
            app.SubjectFocalLength.ValueDisplayFormat = '%.1f';
            app.SubjectFocalLength.Value = 2.6;
            app.SubjectPositionLabel = uilabel(app.SubjectPanel);
            app.SubjectPositionLabel.Text = 'Postion (relative to monitor center):';
            app.SubjectCenteredCheckBox = uicheckbox(app.SubjectPanel);
            app.SubjectCenteredCheckBox.Text = 'centered';
            app.SubjectCenteredCheckBox.Value = true;
            app.XmmEditFieldLabel = uilabel(app.SubjectPanel);
            app.XmmEditFieldLabel.Text = 'X (mm):';
            app.SubjectPositionXEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectPositionXEdit.ValueDisplayFormat = '%11.1f';
            app.SubjectPositionXEdit.Enable = 'off';
            app.YmmLabel = uilabel(app.SubjectPanel);
            app.YmmLabel.Text = 'Y (mm):';
            app.SubjectPositionYEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectPositionYEdit.ValueDisplayFormat = '%11.1f';
            app.SubjectPositionYEdit.Enable = 'off';
            app.ZmmLabel = uilabel(app.SubjectPanel);
            app.ZmmLabel.Text = 'Z (mm):';
            app.SubjectPositionZEdit = uieditfield(app.SubjectPanel, 'numeric');
            app.SubjectPositionZEdit.ValueDisplayFormat = '%11.1f';
            app.SubjectPositionZEdit.Value = 200;
            app.SubjectFOVInfo = uilabel(app.SubjectPanel);
            app.SubjectFOVInfo.Text = {'FOV: 111.8° × 79.4°'; '         5072 µm × 3603 µm (estimated)'};
            app.SubjectInfo = uilabel(app.SubjectPanel);
            app.SubjectInfo.Text = {'Info'; '...'};
            app.SyncPanel = uipanel(app.LayoutProperties);
            app.SyncLabel = uilabel(app.SyncPanel);
            app.SyncLabel.Text = 'Sync:';
            app.ScreenSync = uidropdown(app.SyncPanel);
            app.ScreenSync.Items = {};
            app.ScreenSync.ValueChangedFcn = createCallbackFcn(app, @ScreenSyncValueChanged, true);
            app.ScreenSync.Value = {};
            app.ScreenSyncConnected = uilamp(app.SyncPanel);
            app.LayoutStimulus = uigridlayout(app.LayoutMain);
            app.StimulusPanel = uipanel(app.LayoutStimulus);
            app.StimulusSelectPanel = uipanel(app.LayoutStimulus);
            app.StimulusLabel = uilabel(app.StimulusSelectPanel);
            app.StimulusLabel.Text = 'Stimulus:';
            app.StimulusSelect = uidropdown(app.StimulusSelectPanel);
            app.StimulusSelect.Items = {};
            app.StimulusSelect.Value = {};
            app.RunButton = uibutton(app.StimulusSelectPanel, 'push');
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end